classdef dynoControllerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TemperatureMonitoringLabel    matlab.ui.control.Label
        SafetyMeasurementsLabel       matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        StartButton                   matlab.ui.control.StateButton
        MotorControlLabel             matlab.ui.control.Label
        MeasuredTorqueinlbEditField   matlab.ui.control.NumericEditField
        MeasuredTorqueinlbEditFieldLabel  matlab.ui.control.Label
        MeasuredRotationalSpeedRPMEditField  matlab.ui.control.NumericEditField
        SaveDataButton                matlab.ui.control.Button
        MeasuredRotationalSpeedRPMEditFieldLabel  matlab.ui.control.Label
        DynamometerMeasurementsLabel  matlab.ui.control.Label
        SetTorqueinlbSpinner          matlab.ui.control.Spinner
        SetTorqueinlbSpinnerLabel     matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        SetRotationalSpeedRPMSpinner  matlab.ui.control.Spinner
        SetRotationalSpeedRPMSpinnerLabel  matlab.ui.control.Label
        RotationalSpeedPlot           matlab.ui.control.UIAxes
        TorquePlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        runTimer = 0; % motor running and measurement time
        a; % looking for arduino
        rotationalLine; % Animated Line for rpm
        torqueLine; % Animated Line for torque
        intialTime = 2; % User for timer value;
    end

    methods (Access = private)
        
        function startupFunc(app)
            movegui(app.UIFigure,"center")

            app.a = arduino('COM4','Nano33Iot');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StartButton.Value = 0;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;

            cla(app.TorquePlot,"reset")
            cla(app.RotationalSpeedPlot,"reset")

            app.torqueLine = animatedline(app.TorquePlot);
            app.rotationalLine = animatedline(app.RotationalSpeedPlot);
            
            startTime = 1;

            while app.StopButton == 0

                rotationalData = 4;
                torqueData = 6;

                tp = app.initialTime - startTime;

                addpoints(app.rotationalLine, tp, rotationalData);
                addpoints(app.torqueLine, tp, torqueData);
                
                drawnow;
                app.initialTime = app.initialTime + 1;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TorquePlot = uiaxes(app.UIFigure);
            title(app.TorquePlot, 'Torque (in-lb) vs. Time (sec)')
            xlabel(app.TorquePlot, 'Time (sec)')
            ylabel(app.TorquePlot, 'Torque (in-lb)')
            zlabel(app.TorquePlot, 'Z')
            app.RotationalSpeedPlot = uiaxes(app.UIFigure);
            title(app.RotationalSpeedPlot, 'Rotational Speed (RPM) vs. Time (sec)')
            xlabel(app.RotationalSpeedPlot, 'Time (sec)')
            ylabel(app.RotationalSpeedPlot, 'Rotational Speed (RPM)')
            zlabel(app.RotationalSpeedPlot, 'Z')
            app.SetRotationalSpeedRPMSpinnerLabel = uilabel(app.UIFigure);
            app.SetRotationalSpeedRPMSpinnerLabel.Text = 'Set Rotational Speed (RPM)';
            app.SetRotationalSpeedRPMSpinner = uispinner(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetTorqueinlbSpinnerLabel = uilabel(app.UIFigure);
            app.SetTorqueinlbSpinnerLabel.Text = 'Set Torque  (in-lb)';
            app.SetTorqueinlbSpinner = uispinner(app.UIFigure);
            app.DynamometerMeasurementsLabel = uilabel(app.UIFigure);
            app.DynamometerMeasurementsLabel.Text = 'Dynamometer Measurements';
            app.MeasuredRotationalSpeedRPMEditFieldLabel = uilabel(app.UIFigure);
            app.MeasuredRotationalSpeedRPMEditFieldLabel.Text = 'Measured Rotational Speed (RPM)';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.MeasuredRotationalSpeedRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeasuredTorqueinlbEditFieldLabel = uilabel(app.UIFigure);
            app.MeasuredTorqueinlbEditFieldLabel.Text = 'Measured Torque (in-lb)';
            app.MeasuredTorqueinlbEditField = uieditfield(app.UIFigure, 'numeric');
            app.MotorControlLabel = uilabel(app.UIFigure);
            app.MotorControlLabel.Text = 'Motor Control';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Location'; 'Current Temperature'};
            app.UITable.RowName = {};
            app.SafetyMeasurementsLabel = uilabel(app.UIFigure);
            app.SafetyMeasurementsLabel.Text = 'Safety Measurements';
            app.TemperatureMonitoringLabel = uilabel(app.UIFigure);
            app.TemperatureMonitoringLabel.Text = 'Temperature Monitoring';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynoControllerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end